classdef reflex_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        FolderEndingLabel      matlab.ui.control.Label
        FolderEndingEditField  matlab.ui.control.EditField
        StartButton            matlab.ui.control.Button
        PauseButton            matlab.ui.control.Button
        StopButton             matlab.ui.control.Button
        TextArea               matlab.ui.control.TextArea
        LoadParametersButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
        params  % parameters for the task and for the device
        folder_ending % user input from editField
        loaded_parameters
        adi_doc
        d128
        blockIndex
        secsPerTick
        msec_per_tick
        recording_ticks_before_stim
        recording_ticks_after_stim
        time_vector
        time_column 
        data_matrix
        stimNo
        current 
        step
        score 
        total_reflexes 
        currents_for_threshold 
        stim_no_column 
        current_column 
        score_column 
        total_reflexes_column 
        peri_stim_data
        z_score
    end
    % my functions
    methods (Access = public)
        
        function elapsedTime = inter_stim_interval(app,range_of_intervals)
            % selects a random interval from range_of_intervals,
            % pauses for that duration, and displays the elapsed time.
        
            % Select a random interval value from the given intervals
            random_interval = randsample(range_of_intervals, 1);  % Select a random interval value
            
            % Start the timer
            tic;
            
            % Pause for the randomly selected interval
            pause(random_interval);
            
            % Measure the elapsed time
            elapsedTime = toc;
            
            % Display the elapsed time
%             disp(['Interval time: ', num2str(elapsedTime), ' seconds']);
        end % inter_stim_interval
        
        function device = update_device_value(app,new_value,device)
            % upload new mAmp value to teh device
            % return device
            [success, device] = D128ctrl('enable', device, 0);
            [success, device] = D128ctrl('demand', device, new_value);
            % Upload all parameters to device
            success = D128ctrl('upload', device);
            [success, device] = D128ctrl('enable', device, 1); % make sure it is enabled
        end % update_device_value
        
        
        function is_updated = check_demand_value_on_device(app,device,demand_value,timeout)
            % downloads device status to check if demand value is on the device
            % tries timeout times to upload it, if it was not on the device
            % returns 1 for true and 0 for false
            
            % Download status from device
            [success, device] = D128ctrl('status', device);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % confirm that the parameters were loaded to the device
            if device.demand == demand_value*10
                is_updated = 1;
                return;
            else
                % try timeout times
                for i = 1:timeout
                    [success, device] = D128ctrl('demand', device, new_value);
                    % Upload all parameters to device
                    success = D128ctrl('upload', device);
                    pause(200);
                    % Download status from device
                    [success, device] = D128ctrl('status', device);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % confirm that the parameters were loaded to the device
                    if device.demand == demand_value*10
                        is_updated = 1;
                        return;
                    end
                end
                is_updated = 0;
            end   
        end % check_demand_value_on_device

        function peri_stim_window = get_peri_stim_data(app,labchart_file, stim_threshold, recording_ticks_before_stim, recording_ticks_after_stim)
            % returns data window around the stimulus
            % assumes the stimulus was on channel 2 and emg on channel 1
            nr = labchart_file.NumberOfRecords;
            chan1 = labchart_file.GetChannelData(1,1,nr,1,-1); % channel 1 recent block data (emg with response)
            chan2 = labchart_file.GetChannelData(1,2,nr,1,-1); % channel 2 recent block data (stim channel)
            index_of_stim = find(chan2 > stim_threshold, 1);  % Finds the first index where value is > 0.1
            % keep relevant information in the other channel
            % Calculate the range of indices to extract
            start_index = max(index_of_stim - recording_ticks_before_stim, 1);  % Ensure we don't go below 1
            end_index = min(index_of_stim + recording_ticks_after_stim, length(chan1)); % Ensure we don't exceed vector length
        
            % Extract the segment of data
            peri_stim_window = chan1(start_index:end_index);
        end % get_peri_stim_data

        function z_score = calculate_zscore(app,data_window,time_window,baseline_window_begin,baseline_window_end,reflex_window_begin, reflex_window_end)
            % find beseline data to calculate mean and standard deviation
            % find zscore value in the window after stimulus
            
            % baseline
            baseline_data_indexes = (time_window >= baseline_window_begin) & (time_window <= baseline_window_end);
            % Select the values in data_window that correspond to the time_window range
            baseline_data = data_window(baseline_data_indexes);
            baseline_mean = mean(baseline_data);
            baseline_stdev = std(baseline_data);
            
            % reflex
            reflex_data_indexes = (time_window >= reflex_window_begin) & (time_window <= reflex_window_end);
            reflex_data = data_window(reflex_data_indexes);
            peak_reflex = max(reflex_data);
            z_score = (peak_reflex-baseline_mean)/baseline_stdev;
        end
            end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadParametersButton
        function LoadParametersButtonPushed(app, event)
            
            % Get the value from the Edit Field
            app.folder_ending = strtrim(app.FolderEndingEditField.Value);  
            
            if length(app.folder_ending) == 0
                app.TextArea.Value = 'Please Enter Folder Ending';
            else
                % Get constants from settings function
                app.params = my_settings();
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % SET UP LOGGING
                LOG_FOLDER = "_LOG";
                % Specify the folder path
                LOG_FOLDER_PATH = fullfile(app.params.CURRENT_PATH,LOG_FOLDER);
                % Check if the folder exists
                if exist(LOG_FOLDER_PATH, 'dir') ~= 7
                    % If the folder does not exist, create it
                    mkdir(LOG_FOLDER_PATH);
                    disp(['Folder created: ', LOG_FOLDER_PATH]);
                else
                    % If the folder exists, display a message
                    disp(['Folder already exists: ', LOG_FOLDER_PATH]);
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % SET UP THE STIMULATOR
                % connect digitimer to channel 2 in PowerLab!
                % open device and return handle for further calls
                [success, app.d128] = D128ctrl('open');
                % Download status from device and initial d128 object parameters
                [success, app.d128] = D128ctrl('status', app.d128);
                [success, app.d128] = D128ctrl('enable', app.d128, 0);
                % Set value of pulsewidth (does not upload to device)
                [success, app.d128] = D128ctrl('source', app.d128, 'Internal');
                [success, app.d128] = D128ctrl('pulsewidth', app.d128, app.params.PULSEWIDTH);
                [success, app.d128] = D128ctrl('demand', app.d128, app.params.INIT_mAMP);
                [success, app.d128] = D128ctrl('dwell', app.d128, app.params.DWELL);
                % Upload all parameters to device
                success = D128ctrl('upload', app.d128);
                [success, app.d128] = D128ctrl('enable', app.d128, 1); % make sure it is enabled
                % Download status from device
                [success, app.d128] = D128ctrl('status', app.d128);
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % confirm that the parameters were loaded to the device
                if app.d128.pulsewidth == app.params.PULSEWIDTH & app.d128.demand == app.params.INIT_mAMP*10
                    app.loaded_parameters = 1;
                    app.TextArea.Value = 'SUCCESS. Parameters loaded';
                else
                    app.loaded_parameters = 0;
                    app.TextArea.Value = 'FAILED to load Parameters on the device';
                    return;
                end
                    % Start LabChart
                    app.adi_doc = actxserver("ADIChart.Document");
                    adi.gLCApp = app.adi_doc.Application;
                    app.adi_doc.Close();
                    % Open the file specified by path, and return a reference to that LabChart adi.document.
                    app.adi_doc = adi.gLCApp.Open(app.params.PATH2TEMPLATE);
                    app.blockIndex = 0;
                    app.secsPerTick = app.adi_doc.GetRecordSecsPerTick(app.blockIndex);
                    app.msec_per_tick = app.secsPerTick *1000; % get how many ms is one tick
                    app.recording_ticks_before_stim = floor(app.params.RECORDING_MS_BEFORE_STIM/app.msec_per_tick); % how many ticks is my before window
                    app.recording_ticks_after_stim = floor(app.params.RECORDING_MS_AFTER_STIM/app.msec_per_tick);   % how many ticks is my after window
                    % create time vector for that
                    app.time_vector = (-app.recording_ticks_before_stim : app.recording_ticks_after_stim) * app.msec_per_tick;
                    % Convert to column vector
                    app.time_column = app.time_vector(:); % the colon operator (:) converts to a column vector
                    % Initialize the matrix with the first column
                    app.data_matrix = app.time_column;
                    
                    app.stimNo = 0;
                    app.current = app.params.INIT_mAMP;
                    app.step = app.params.STEP_UP_BIG_mAMP;
                    app.score = 0;
                    app.total_reflexes = 0;
                    app.currents_for_threshold = [];
                    app.stim_no_column = [];
                    app.current_column = [];
                    app.score_column = [];
                    app.total_reflexes_column = [];
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.loaded_parameters
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % main program loop that will keep delivering the stimuli
                while (app.current >= app.params.INIT_mAMP && app.current <= app.params.MAX_mAMP) && app.total_reflexes < app.params.MAX_REFLEXES
                    app.stimNo = app.stimNo + 1;
                    app.TextArea.Value{end+1} = '';
                    app.TextArea.Value{end+1} =['Stimulus number: ', num2str(app.stimNo)];
                    app.TextArea.Value{end+1} = ['Current: ', num2str(app.current), ' mA'];
    %                 fprintf(['\n\nStimulus number: ', num2str(stimNo),'\n']);
    %                 disp(['Current: ', num2str(current), ' mA']);
                    % wait inter stimulus interval
                    interval = app.inter_stim_interval(app.params.INTER_STIM_INTERVALS);
                    app.TextArea.Value{end+1} = ['Interval: ', num2str(interval), ' seconds'];
                    % start recording
                    app.adi_doc.StartSampling;
                    pause((app.params.RECORDING_MS_BEFORE_STIM+500)/1000) % how much to wait before the stim (add some tolerance:500ms)
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % trigger the device
                    success = D128ctrl('Trigger', app.d128);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    pause((app.params.RECORDING_MS_AFTER_STIM+500)/1000)   % how much to wait after the stim before stopping the recording (add some tolerance:500ms)
                
                    app.adi_doc.StopSampling;
                    
                    % get peri stim data
                    app.peri_stim_data = app.get_peri_stim_data(app.adi_doc, app.params.STIM_THRESHOLD_V,app.recording_ticks_before_stim, app.recording_ticks_after_stim);
                    % calculate zscore
                    app.z_score = app.calculate_zscore(app.peri_stim_data,app.time_vector,app.params.BASELINE_WINDOW_BEGIN_MS,app.params.BASELINE_WINDOW_END_MS,app.params.REFLEX_WINDOW_BEGIN_MS,app.params.REFLEX_WINDOW_END_MS);
                    app.TextArea.Value{end+1} = ['Z-score: ', num2str(app.z_score)];
                    
                    if app.z_score > app.params.Z_SCORE_THRESHOLD
                        % if it is the first reflex or z-score was above the threshold when
                        % increasing current, count it as a reflex and
                        % increment the total reflexes
                        if sum(app.total_reflexes_column) == 0 || app.step > 0
                            app.total_reflexes = app.total_reflexes + 1;
                            app.currents_for_threshold = [app.currents_for_threshold,app.current];
                        end
                        app.step = app.params.STEP_DOWN_mAMP;
                    else % no reflex
                       if sum(app.total_reflexes_column) == 0 
                           % if there were no reflexes before, go up faster
                           app.step = app.params.STEP_UP_BIG_mAMP;
                       else
                           % if there were any reflexes before, go up slower
                           app.step = app.params.STEP_UP_mAMP;
                       end 
                    end
                    app.TextArea.Value{end+1} = ['Total reflexes: ', num2str(app.total_reflexes)];
                    % Append the new column to the data_matrix
                    % Convert to column vector
                    app.peri_stim_data = app.peri_stim_data(:); % the colon operator (:) converts to a column vector
                    app.data_matrix = [app.data_matrix, app.peri_stim_data];
                    % add logging info
                    app.stim_no_column = [app.stim_no_column,app.stimNo];
                    app.current_column = [app.current_column,app.current];
                    app.score_column = [app.score_column,app.z_score];
                    app.total_reflexes_column = [app.total_reflexes_column,app.total_reflexes];
                    app.current = app.current+app.step;
                    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Upload new values to the device
                    app.d128 = app.update_device_value(app.current,app.d128);
                    % Make sure it is loaded to the device
                    is_updated = app.check_demand_value_on_device(app.d128,app.current,3);
                    if ~is_updated
                        app.TextArea.Value = 'Could not upload new value to the device';
%                         disp('Could not upload new value to the device');
                        return;
                    end
                 end % end while loop
            else
                app.TextArea.Value = 'First Press: Load Parameters Button';
            end % if parameters were loaded
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FolderEndingLabel = uilabel(app.UIFigure);
            app.FolderEndingLabel.Text = 'Folder Ending:';
            app.FolderEndingEditField = uieditfield(app.UIFigure, 'text');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.Text = 'Stop';
            app.TextArea = uitextarea(app.UIFigure);
            app.LoadParametersButton = uibutton(app.UIFigure, 'push');
            app.LoadParametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadParametersButtonPushed, true);
            app.LoadParametersButton.Text = 'Load Parameters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = reflex_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end